classdef momenta_equalization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        savefactorsButton        matlab.ui.control.Button
        calculateButton          matlab.ui.control.Button
        calfac_pzEditField       matlab.ui.control.NumericEditField
        calfac_pzEditFieldLabel  matlab.ui.control.Label
        calfac_pyEditField       matlab.ui.control.NumericEditField
        calfac_pyEditFieldLabel  matlab.ui.control.Label
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
          binsize_px = evalin('base','binsize_px');
          binsize_py = evalin('base','binsize_py');
          binsize_pz = evalin('base','binsize_pz');
         
          px1 = evalin('base','px1');
          py1 = evalin('base','py1');
          pz1 = evalin('base','pz1');
          
          px2 = evalin('base','px2');
          py2 = evalin('base','py2');
          pz2 = evalin('base','pz2');
          
          calfac_py=app.calfac_pyEditField.Value;
          calfac_pz=app.calfac_pzEditField.Value;
          
          px1_cal=px1;
          px2_cal=px2;
          py1_cal=calfac_py*py1;
          py2_cal=calfac_py*py2;
          pz1_cal=calfac_pz*pz1;
          pz2_cal=calfac_pz*pz2;
          
                   
          assignin('base', 'px1_cal', px1_cal);
          assignin('base', 'px2_cal', px2_cal);
          assignin('base', 'py1_cal', py1_cal);
          assignin('base', 'py2_cal', py2_cal);
          assignin('base', 'pz1_cal', pz1_cal);
          assignin('base', 'pz2_cal', pz2_cal);
          
          edge_max=ceil(max([max([px1_cal;px2_cal;py1_cal;py2_cal;pz1_cal;pz2_cal]); -min([px1_cal;px2_cal;py1_cal;py2_cal;pz1_cal;pz2_cal])]));

edges_px=[-edge_max:binsize_px:edge_max];
edges_py=[-edge_max:binsize_py:edge_max]; 
edges_pz=[-edge_max:binsize_pz:edge_max];

i_px=1:length(edges_px)-1;
i_py=1:length(edges_py)-1;
i_pz=1:length(edges_pz)-1;

bincent_px=[];
bincent_py=[];
bincent_pz=[];

bincent_px(i_px)=(edges_px(i_px)+edges_px(i_px+1))/2;
bincent_py(i_py)=(edges_py(i_py)+edges_py(i_py+1))/2;
bincent_pz(i_pz)=(edges_pz(i_pz)+edges_pz(i_pz+1))/2;

[px1_counts,px1_edges]=histcounts(px1_cal,edges_px);
[py1_counts,py1_edges]=histcounts(py1_cal,edges_py);
[pz1_counts,pz1_edges]=histcounts(pz1_cal,edges_pz);

[px2_counts,px2_edges]=histcounts(px2_cal,edges_px);
[py2_counts,py2_edges]=histcounts(py2_cal,edges_py);
[pz2_counts,pz2_edges]=histcounts(pz2_cal,edges_pz);

plot(app.UIAxes, bincent_px,px1_counts,'r',bincent_py,py1_counts,'b',bincent_pz,pz1_counts,'k','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes,'on');
xlim(app.UIAxes,[-300 300]);
%ylim([0 max(px1_counts)*1.1]);
xlabel(app.UIAxes,'px1/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes,'counts', 'FontWeight', 'normal','FontName', 'Arial');
legend(app.UIAxes,{'px1','py1','pz1'}, 'FontSize', 20, 'FontWeight', 'normal','FontName', 'Arial')

plot(app.UIAxes_2,bincent_px,px2_counts,'r',bincent_py,py2_counts,'b',bincent_pz,pz2_counts,'k','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_2,'on');
xlim(app.UIAxes_2,[-300 300]);
%ylim([0 max(px1_counts)*1.1]);
xlabel(app.UIAxes_2,'px1/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_2,'counts', 'FontWeight', 'normal','FontName', 'Arial');
legend(app.UIAxes_2,{'px2','py2','pz2'}, 'FontSize', 20, 'FontWeight', 'normal','FontName', 'Arial')

plot(app.UIAxes_3, bincent_px,px1_counts,'r',bincent_py,py2_counts,'b','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_3,'on');
xlim(app.UIAxes_3,[-300 300]);
%ylim([0 max(px1_counts)*1.1]);
xlabel(app.UIAxes_3,'px1/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_3,'counts', 'FontWeight', 'normal','FontName', 'Arial');
legend(app.UIAxes_3,{'px1','py2'}, 'FontSize', 20, 'FontWeight', 'normal','FontName', 'Arial')


plot(app.UIAxes_4, bincent_px,px2_counts,'r',bincent_py,py1_counts,'b','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_4,'on');
xlim(app.UIAxes_4,[-300 300]);
%ylim([0 max(px1_counts)*1.1]);
xlabel(app.UIAxes_4,'px1/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_4,'counts', 'FontWeight', 'normal','FontName', 'Arial');
legend(app.UIAxes_4,{'px2','py1'}, 'FontSize', 20, 'FontWeight', 'normal','FontName', 'Arial')


        end

        % Button pushed function: savefactorsButton
        function savefactorsButtonPushed(app, event)
            assignin('base', 'calfac_py', app.calfac_pyEditField.Value);
            assignin('base', 'calfac_pz', app.calfac_pzEditField.Value);            
            cal_py_pz_para=[app.calfac_pyEditField.Value; app.calfac_pzEditField.Value];
            dlmwrite('cal_py_pz_para.txt',cal_py_pz_para);
   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_4, 'Z')
            app.calfac_pyEditFieldLabel = uilabel(app.UIFigure);
            app.calfac_pyEditFieldLabel.Text = 'calfac_py';
            app.calfac_pyEditField = uieditfield(app.UIFigure, 'numeric');
            app.calfac_pyEditField.Value = 1;
            app.calfac_pzEditFieldLabel = uilabel(app.UIFigure);
            app.calfac_pzEditFieldLabel.Text = 'calfac_pz';
            app.calfac_pzEditField = uieditfield(app.UIFigure, 'numeric');
            app.calfac_pzEditField.Value = 1.18;
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.savefactorsButton = uibutton(app.UIFigure, 'push');
            app.savefactorsButton.ButtonPushedFcn = createCallbackFcn(app, @savefactorsButtonPushed, true);
            app.savefactorsButton.Text = 'savefactors';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = momenta_equalization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end