classdef momentum_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        y03EditField         matlab.ui.control.NumericEditField
        y03EditFieldLabel    matlab.ui.control.Label
        x03EditField         matlab.ui.control.NumericEditField
        x03EditFieldLabel    matlab.ui.control.Label
        savevariablesButton  matlab.ui.control.Button
        x02EditField         matlab.ui.control.NumericEditField
        x02EditFieldLabel    matlab.ui.control.Label
        t0EditField          matlab.ui.control.NumericEditField
        t0EditField_2Label   matlab.ui.control.Label
        y02EditField         matlab.ui.control.NumericEditField
        y02EditField_2Label  matlab.ui.control.Label
        y01EditField         matlab.ui.control.NumericEditField
        y01EditField_2Label  matlab.ui.control.Label
        x01EditField         matlab.ui.control.NumericEditField
        x01EditField_2Label  matlab.ui.control.Label
        calculateButton      matlab.ui.control.StateButton
        UIAxes_12            matlab.ui.control.UIAxes
        UIAxes_11            matlab.ui.control.UIAxes
        UIAxes_10            matlab.ui.control.UIAxes
        UIAxes_9             matlab.ui.control.UIAxes
        UIAxes_8             matlab.ui.control.UIAxes
        UIAxes_7             matlab.ui.control.UIAxes
        UIAxes_6             matlab.ui.control.UIAxes
        UIAxes_5             matlab.ui.control.UIAxes
        UIAxes_4             matlab.ui.control.UIAxes
        UIAxes_3             matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: calculateButton
        function calculateButtonValueChanged(app, event)
          %  value = app.calculateButton.Value;
          mmns_to_au_conv = evalin('base','mmns_to_au_conv');
          hatoev = evalin('base','hatoev');
          mx1 = evalin('base','mx1');
          cx1 = evalin('base','cx1');
          my1 = evalin('base','my1');
          cy1 = evalin('base','cy1');
          mt1 = evalin('base','mt1');
          ct1 = evalin('base','ct1');
          
          mx2 = evalin('base','mx2');
          cx2 = evalin('base','cx2');
          my2 = evalin('base','my2');
          cy2 = evalin('base','cy2');
          mt2 = evalin('base','mt2');
          ct2 = evalin('base','ct2');
          
          mx3 = evalin('base','mx3');
          cx3 = evalin('base','cx3');
          my3 = evalin('base','my3');
          cy3 = evalin('base','cy3');
          mt3 = evalin('base','mt3');
          ct3 = evalin('base','ct3');
          
          binsize_px = evalin('base','binsize_px');
          binsize_py = evalin('base','binsize_py');
          binsize_pz = evalin('base','binsize_pz');
          binsize_p = evalin('base','binsize_p');
          
                   
          frag_m_z = evalin('base','frag_m_z');
          
          x1_gate_rot = evalin('base','x1_gate_rot');
          y1_gate_rot = evalin('base','y1_gate_rot');
          t1_gate_rot = evalin('base','t1_gate_rot');

          x2_gate_rot = evalin('base','x2_gate_rot');
          y2_gate_rot = evalin('base','y2_gate_rot');
          t2_gate_rot = evalin('base','t2_gate_rot');
          
          x3_gate_rot = evalin('base','x3_gate_rot');
          y3_gate_rot = evalin('base','y3_gate_rot');
          t3_gate_rot = evalin('base','t3_gate_rot');
          
%           binsize_px = evalin('base','binsize_px');
%           binsize_py = evalin('base','binsize_py');
%           binsize_pz = evalin('base','binsize_pz');
%           binsize_p = evalin('base','binsize_p');

         
          x01=app.x01EditField.Value;
          x02=app.x02EditField.Value;
          x03=app.x03EditField.Value;
          y01=app.y01EditField.Value;
          y02=app.y02EditField.Value;
          y03=app.y03EditField.Value;
          t01=app.t0EditField.Value;
          t02=app.t0EditField.Value;
          t03=app.t0EditField.Value;
          
          px1 = frag_m_z(1)*((x1_gate_rot-x01).*mx1 + cx1)*mmns_to_au_conv;
          py1 = frag_m_z(1)*((y1_gate_rot-y01).*my1 + cy1)*mmns_to_au_conv;
          pz1 = frag_m_z(1)*((t1_gate_rot-t01).*mt1 + ct1)*mmns_to_au_conv;  
          p1=sqrt(px1.^2+py1.^2+pz1.^2);   

          px2 = frag_m_z(2)*((x2_gate_rot-x02).*mx2 + cx2)*mmns_to_au_conv;
          py2 = frag_m_z(2)*((y2_gate_rot-y02).*my2 + cy2)*mmns_to_au_conv;
          pz2 = frag_m_z(2)*((t2_gate_rot-t02).*mt2 + ct2)*mmns_to_au_conv;  
          p2=sqrt(px2.^2+py2.^2+pz2.^2);
          
          px3 = frag_m_z(3)*((x3_gate_rot-x03).*mx3 + cx3)*mmns_to_au_conv;
          py3 = frag_m_z(3)*((y3_gate_rot-y03).*my3 + cy3)*mmns_to_au_conv;
          pz3 = frag_m_z(3)*((t3_gate_rot-t03).*mt3 + ct3)*mmns_to_au_conv;  
          p3=sqrt(px3.^2+py3.^2+pz3.^2);
          
%           %randomization
%           p_raw=[px1 py1 pz1 px2 py2 pz2 ];
%           ind_rand = zeros(length(p_raw(:,1)),6);
% 
%             for i=1:length(p_raw(:,1))
%             for j=1:6
% 
%             ind_rand_12 = (randperm(2))*3-2;
% 
%             ind_rand_12 = [ind_rand_12(1) ind_rand_12(1)+1  ind_rand_12(1)+2 ...
%               ind_rand_12(2) ind_rand_12(2)+1  ind_rand_12(2)+2 ];
% 
%             ind_rand(i,:) = ind_rand_12;
% 
%             end
%             end
% 
%             p_raw_new = zeros(length(p_raw(:,1)),6);
% 
%            for i=1:length(p_raw(:,1))
%            for j=1:6
%     
%            p_raw_new(i,j)=p_raw(i,ind_rand(i,j)) ;
%            end
%            end
% 
%            px1=p_raw_new(:,1); py1=p_raw_new(:,2); pz1=p_raw_new(:,3);
%            px2=p_raw_new(:,4); py2=p_raw_new(:,5); pz2=p_raw_new(:,6);

              
            assignin('base', 'px1', px1);
            assignin('base', 'px2', px2);
            assignin('base', 'px3', px3);
            assignin('base', 'py1', py1);
            assignin('base', 'py2', py2);
            assignin('base', 'py3', py3);
            assignin('base', 'pz1', pz1);
            assignin('base', 'pz2', pz2);
            assignin('base', 'pz3', pz3);
            assignin('base', 'p1', p1);
            assignin('base', 'p2', p2);
            assignin('base', 'p3', p3);
          
          edge_max=ceil(max([max([px1;px2;px3;py1;py2;py3;pz1;pz2;pz3]); -min([px1;px2;px3;py1;py2;py3;pz1;pz2;pz3])]));

edges_px=[-edge_max:binsize_px:edge_max];
edges_py=[-edge_max:binsize_py:edge_max]; 
edges_pz=[-edge_max:binsize_pz:edge_max];
edges_p=[min([p1;p2]):binsize_p:max([p1;p2])];

i_px=1:length(edges_px)-1;
i_py=1:length(edges_py)-1;
i_pz=1:length(edges_pz)-1;
i_p=1:length(edges_p)-1;

bincent_px=[];
bincent_py=[];
bincent_pz=[];
bincent_p=[];


bincent_px(i_px)=(edges_px(i_px)+edges_px(i_px+1))/2;
bincent_py(i_py)=(edges_py(i_py)+edges_py(i_py+1))/2;
bincent_pz(i_pz)=(edges_pz(i_pz)+edges_pz(i_pz+1))/2;
bincent_p(i_p)=(edges_p(i_p)+edges_p(i_p+1))/2;


[px1_counts,px1_edges]=histcounts(px1,edges_px);
[py1_counts,py1_edges]=histcounts(py1,edges_py);
[pz1_counts,pz1_edges]=histcounts(pz1,edges_pz);
[p1_counts,p1_edges]=histcounts(p1,edges_p);


[px2_counts,px2_edges]=histcounts(px2,edges_px);
[py2_counts,py2_edges]=histcounts(py2,edges_py);
[pz2_counts,pz2_edges]=histcounts(pz2,edges_pz);
[p2_counts,p2_edges]=histcounts(p2,edges_p);

[px3_counts,px3_edges]=histcounts(px3,edges_px);
[py3_counts,py3_edges]=histcounts(py3,edges_py);
[pz3_counts,pz3_edges]=histcounts(pz3,edges_pz);
[p3_counts,p3_edges]=histcounts(p3,edges_p);

[px_counts,px_edges]=histcounts(px1+px2+px3,edges_px);
[py_counts,py_edges]=histcounts(py1+py2+py3,edges_py);
[pz_counts,pz_edges]=histcounts(pz1+pz2+pz3,edges_pz);


plot(app.UIAxes,bincent_px,px1_counts,'r',bincent_px,fliplr(px1_counts),'b',bincent_px,(px1_counts+fliplr(px1_counts))./2,'k','LineWidth',2);
% set(app.UIAxes, gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes,'on');
xlim(app.UIAxes,[-400 400]);
ylim(app.UIAxes,[0 max(px1_counts)*1.1]);
xlabel(app.UIAxes,'px1/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes,'counts', 'FontWeight', 'normal','FontName', 'Arial');
legend(app.UIAxes,{'px1','-px1','px1\_avg'}, 'FontSize', 10, 'FontWeight', 'normal','FontName', 'Arial')

plot(app.UIAxes_2, bincent_px,px2_counts,'r',bincent_px,fliplr(px2_counts),'b',bincent_px,(px2_counts+fliplr(px2_counts))./2,'k','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_2,'on');
xlim(app.UIAxes_2,[-400 400]);
ylim(app.UIAxes_2,[0 max(px2_counts)*1.1]);
xlabel(app.UIAxes_2,'px2/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_2,'counts', 'FontWeight', 'normal','FontName', 'Arial');
% ytickformat('%0.0e')
legend(app.UIAxes_2,{'px2','-px2','px2\_avg'}, 'FontSize', 10, 'FontWeight', 'normal','FontName', 'Arial')

plot(app.UIAxes_10, bincent_px,px3_counts,'r',bincent_px,fliplr(px3_counts),'b',bincent_px,(px3_counts+fliplr(px3_counts))./2,'k','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_10,'on');
xlim(app.UIAxes_10,[-400 400]);
ylim(app.UIAxes_10,[0 max(px3_counts)*1.1]);
xlabel(app.UIAxes_10,'px3/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_10,'counts', 'FontWeight', 'normal','FontName', 'Arial');
% ytickformat('%0.0e')
legend(app.UIAxes_10,{'px3','-px3','px3\_avg'}, 'FontSize', 10, 'FontWeight', 'normal','FontName', 'Arial')

plot(app.UIAxes_3, bincent_px,px_counts,'r','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_3,'on');
xlim(app.UIAxes_3,[-100 100]);
%ylim([0 max(px_counts)*1.1]);
xlabel(app.UIAxes_3,'\Sigmapx/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_3,'counts', 'FontWeight', 'normal','FontName', 'Arial');
legend(app.UIAxes_3, {'\Sigmapx'}, 'FontSize', 10, 'FontWeight', 'normal','FontName', 'Arial')

plot(app.UIAxes_4, bincent_py,py1_counts,'r',bincent_py,fliplr(py1_counts),'b',bincent_py,(py1_counts+fliplr(py1_counts))./2,'k','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_4,'on');
xlim(app.UIAxes_4,[-400 400]);
ylim(app.UIAxes_4,[0 max(py1_counts)*1.1]);
xlabel(app.UIAxes_4,'py1/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_4,'counts', 'FontWeight', 'normal','FontName', 'Arial');
legend(app.UIAxes_4,{'py1','-py1','py1\_avg'}, 'FontSize', 10, 'FontWeight', 'normal','FontName', 'Arial')

plot(app.UIAxes_5, bincent_py,py2_counts,'r',bincent_py,fliplr(py2_counts),'b',bincent_py,(py2_counts+fliplr(py2_counts))./2,'k','LineWidth',2);
% set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_5,'on');
xlim(app.UIAxes_5,[-400 400]);
ylim(app.UIAxes_5,[0 max(py2_counts)*1.1]);
xlabel(app.UIAxes_5,'py2/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_5,'counts', 'FontWeight', 'normal','FontName', 'Arial');
% ytickformat('%0.0e')
legend(app.UIAxes_5,{'py2','-py2','py2\_avg'}, 'FontSize', 10, 'FontWeight', 'normal','FontName', 'Arial')

plot(app.UIAxes_11, bincent_py,py3_counts,'r',bincent_py,fliplr(py3_counts),'b',bincent_py,(py3_counts+fliplr(py3_counts))./2,'k','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_11,'on');
xlim(app.UIAxes_11,[-400 400]);
ylim(app.UIAxes_11,[0 max(py3_counts)*1.1]);
xlabel(app.UIAxes_11,'py3/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_11,'counts', 'FontWeight', 'normal','FontName', 'Arial');
% ytickformat('%0.0e')
legend(app.UIAxes_11,{'py3','-py3','py3\_avg'}, 'FontSize', 10, 'FontWeight', 'normal','FontName', 'Arial')

plot(app.UIAxes_6, bincent_py,py_counts,'r','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_6,'on');
xlim(app.UIAxes_6, [-100 100]);
%ylim([0 max(py_counts)*1.1]);
xlabel(app.UIAxes_6,'\Sigmapy/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_6,'counts', 'FontWeight', 'normal','FontName', 'Arial');
legend(app.UIAxes_6,{'\Sigmapy'}, 'FontSize', 10, 'FontWeight', 'normal','FontName', 'Arial')

plot(app.UIAxes_7, bincent_pz,pz1_counts,'r',bincent_pz,fliplr(pz1_counts),'b',bincent_pz,(pz1_counts+fliplr(pz1_counts))./2,'k','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_7,'on');
xlim(app.UIAxes_7,[-400 400]);
ylim(app.UIAxes_7, [0 max(pz1_counts)*1.1]);
xlabel(app.UIAxes_7,'pz1/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_7,'counts', 'FontWeight', 'normal','FontName', 'Arial');
legend(app.UIAxes_7,{'pz1','-pz1','pz1\_avg'}, 'FontSize', 10, 'FontWeight', 'normal','FontName', 'Arial')


plot(app.UIAxes_8,bincent_pz,pz2_counts,'r',bincent_pz,fliplr(pz2_counts),'b',bincent_pz,(pz2_counts+fliplr(pz2_counts))./2,'k','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_8,'on');
xlim(app.UIAxes_8,[-400 400]);
ylim(app.UIAxes_8,[0 max(pz2_counts)*1.1]);
xlabel(app.UIAxes_8,'pz2/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_8,'counts', 'FontWeight', 'normal','FontName', 'Arial');
% ytickformat('%0.0e')
legend(app.UIAxes_8,{'pz2','-pz2','pz2\_avg'}, 'FontSize', 10, 'FontWeight', 'normal','FontName', 'Arial')

plot(app.UIAxes_12, bincent_pz,pz3_counts,'r',bincent_py,fliplr(pz3_counts),'b',bincent_py,(pz3_counts+fliplr(pz3_counts))./2,'k','LineWidth',2);
%set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_12,'on');
xlim(app.UIAxes_12,[-400 400]);
ylim(app.UIAxes_12,[0 max(pz3_counts)*1.1]);
xlabel(app.UIAxes_12,'pz3/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_12,'counts', 'FontWeight', 'normal','FontName', 'Arial');
% ytickformat('%0.0e')
legend(app.UIAxes_12,{'pz3','-pz3','pz3\_avg'}, 'FontSize', 10, 'FontWeight', 'normal','FontName', 'Arial')

plot(app.UIAxes_9, bincent_pz,pz_counts,'r','LineWidth',2);
% set(gca, 'XScale', 'linear','YScale', 'linear','FontSize',30,'GridColor', [0.1, 0.7, 0.2],'GridAlpha',0.95,'LineWidth',1 );
grid(app.UIAxes_9,'on');
xlim(app.UIAxes_9,[-100 100]);
%ylim([0 max(pz_counts)*1.1]);
xlabel(app.UIAxes_9, '\Sigmapz/au',  'FontWeight', 'normal','FontName', 'Arial');
ylabel(app.UIAxes_9, 'counts', 'FontWeight', 'normal','FontName', 'Arial');
legend(app.UIAxes_9, {'\Sigmapz'}, 'FontSize', 10, 'FontWeight', 'normal','FontName', 'Arial')

            
        end

        % Button pushed function: savevariablesButton
        function savevariablesButtonPushed(app, event)
            assignin('base', 'x01', app.x01EditField.Value);
            assignin('base', 'x02', app.x02EditField.Value);
            assignin('base', 'x03', app.x03EditField.Value);
            assignin('base', 'y01', app.y01EditField.Value);
            assignin('base', 'y02', app.y02EditField.Value);
            assignin('base', 'y03', app.y03EditField.Value);
            assignin('base', 't0', app.t0EditField.Value);
                
            pos_para=[app.x01EditField.Value; app.x02EditField.Value; app.x03EditField.Value; app.y01EditField.Value; app.y02EditField.Value; app.y03EditField.Value; app.t0EditField.Value];
            dlmwrite('pos_para.txt',pos_para);
%             save('pos_para', 'pos_para', '-v7.3')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'px')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_6, 'px')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_10 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_11 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_12 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_12, 'Z')
            app.calculateButton = uibutton(app.UIFigure, 'state');
            app.calculateButton.ValueChangedFcn = createCallbackFcn(app, @calculateButtonValueChanged, true);
            app.calculateButton.Text = 'calculate';
            app.x01EditField_2Label = uilabel(app.UIFigure);
            app.x01EditField_2Label.Text = 'x01';
            app.x01EditField = uieditfield(app.UIFigure, 'numeric');
            app.x01EditField.Value = 0.7;
            app.y01EditField_2Label = uilabel(app.UIFigure);
            app.y01EditField_2Label.Text = 'y01';
            app.y01EditField = uieditfield(app.UIFigure, 'numeric');
            app.y01EditField.Value = 1;
            app.y02EditField_2Label = uilabel(app.UIFigure);
            app.y02EditField_2Label.Text = 'y02';
            app.y02EditField = uieditfield(app.UIFigure, 'numeric');
            app.y02EditField.Value = 1.3;
            app.t0EditField_2Label = uilabel(app.UIFigure);
            app.t0EditField_2Label.Text = 't0';
            app.t0EditField = uieditfield(app.UIFigure, 'numeric');
            app.t0EditField.Value = -288.2;
            app.x02EditFieldLabel = uilabel(app.UIFigure);
            app.x02EditFieldLabel.Text = 'x02';
            app.x02EditField = uieditfield(app.UIFigure, 'numeric');
            app.x02EditField.Value = 1;
            app.savevariablesButton = uibutton(app.UIFigure, 'push');
            app.savevariablesButton.ButtonPushedFcn = createCallbackFcn(app, @savevariablesButtonPushed, true);
            app.savevariablesButton.Text = 'save variables';
            app.x03EditFieldLabel = uilabel(app.UIFigure);
            app.x03EditFieldLabel.Text = 'x03';
            app.x03EditField = uieditfield(app.UIFigure, 'numeric');
            app.x03EditField.Value = 0.8;
            app.y03EditFieldLabel = uilabel(app.UIFigure);
            app.y03EditFieldLabel.Text = 'y03';
            app.y03EditField = uieditfield(app.UIFigure, 'numeric');
            app.y03EditField.Value = 0.8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = momentum_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end